classdef barzegar_sangari < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FinalTimesEditFieldLabel     matlab.ui.control.Label
        FinalTimesEditField          matlab.ui.control.NumericEditField
        RunButton                    matlab.ui.control.Button
        DampingRatioEditFieldLabel   matlab.ui.control.Label
        DampingRatioEditField        matlab.ui.control.NumericEditField
        TimeStepsEditFieldLabel      matlab.ui.control.Label
        TimeStepsEditField           matlab.ui.control.NumericEditField
        ConstantLinearDropDownLabel  matlab.ui.control.Label
        ConstantLinearDropDown       matlab.ui.control.DropDown
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)


[FileName,PathName] = uigetfile({'*.*','All Files (*.*)'}, ...
'Pick a File');

path=strcat(PathName,FileName);

Eq=load(path);

Eq=Eq';

Eq=Eq(:);

Eq=Eq*9.81;

Eq=Eq';

m=1;
e=app.DampingRatioEditField.Value;


tf=app.FinalTimesEditField.Value;
dt=app.TimeStepsEditField.Value;


if app.ConstantLinearDropDown.Value=="Constant"
    gamma=0.5;
    beta=0.25;
else
    gamma=0.5;
    beta=0.1666;
end


p=-m*Eq;

u=zeros(1,length(Eq));
ud=zeros(1,length(Eq));
udd=zeros(1,length(Eq));

u(1)=0;
ud(1)=0;
udd(1)=p(1);


it=(tf/dt);

Sa=zeros(1,400);    
Sd=zeros(1,400);    


for j=0.01:0.01:4
    
    T=0.01+j;
    o=2*3.14/T;
    k=m*(o^2);
    c=2*m*e*o;

    %udd=(p(1)-c*ud-k*u)/m;
    a1=(m/(beta*(dt^2)))+((gamma*c)/(beta*dt));
    a2=(m/(beta*dt))+((gamma/beta)-1)*c;
    a3=(((1/(2*beta))-1)*m)+dt*c*((gamma/(2*beta))-1);
    khat=k+a1;


        for i=2:it
        
        ph(i)=p(i)+a1*u(i-1)+a2*ud(i-1)+a3*udd(i-1);
    
        u(i)=ph(i)/khat;
    
        ud(i)=(((gamma)*(u(i)-u(i-1)))/(beta*dt))+(1-(gamma/beta))*ud(i-1)+(1-(gamma/(2*beta)))*dt*udd(i-1);
    
        udd(i)=((u(i)-u(i-1))/(beta*dt^2))-(ud(i-1)/(beta*dt))-((1/(2*beta))-1)*udd(i-1);
        
        end
        
u2=u(isfinite(u));
u3=max(abs(u2));
Sd(round(j*100))=u3;
Sa(round(j*100))=Sd(round(j*100))*o^2;       
        
end


time=0.01:0.01:4;
figure;
subplot(1,2,1);
plot(time,Sa,'linewidth',2);
xlabel('Period [sec]');
ylabel('Sa [M/s^2]');

subplot(1,2,2);
plot(time,Sd,'linewidth',2);
xlabel('Period [sec]');
ylabel('Sd [Meters]');
grid on;


        end

        % Value changed function: ConstantLinearDropDown
        function ConstantLinearDropDownValueChanged(app, event)
            value = app.ConstantLinearDropDown.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FinalTimesEditFieldLabel = uilabel(app.UIFigure);
            app.FinalTimesEditFieldLabel.Text = 'Final Time (s)';
            app.FinalTimesEditField = uieditfield(app.UIFigure, 'numeric');
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.DampingRatioEditFieldLabel = uilabel(app.UIFigure);
            app.DampingRatioEditFieldLabel.Text = 'Damping Ratio';
            app.DampingRatioEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeStepsEditFieldLabel = uilabel(app.UIFigure);
            app.TimeStepsEditFieldLabel.Text = 'Time Step (s)';
            app.TimeStepsEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConstantLinearDropDownLabel = uilabel(app.UIFigure);
            app.ConstantLinearDropDownLabel.Text = 'Constant/Linear';
            app.ConstantLinearDropDown = uidropdown(app.UIFigure);
            app.ConstantLinearDropDown.Items = {'Constant', 'Linear'};
            app.ConstantLinearDropDown.ValueChangedFcn = createCallbackFcn(app, @ConstantLinearDropDownValueChanged, true);
            app.ConstantLinearDropDown.Value = 'Constant';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = barzegar_sangari

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end